classdef GMGDataPicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GMGDataPickerUIFigure    matlab.ui.Figure
        ScreenshotsPanel         matlab.ui.container.Panel
        Switch                   matlab.ui.control.Switch
        DropDown                 matlab.ui.control.DropDown
        ProfiledataPanel         matlab.ui.container.Panel
        SavescreenshotButton     matlab.ui.control.Button
        ClearButton_2            matlab.ui.control.Button
        LoadButton_2             matlab.ui.control.Button
        PickingPanel             matlab.ui.container.Panel
        LoadButton_3             matlab.ui.control.Button
        CompareButton            matlab.ui.control.Button
        ContinueButton           matlab.ui.control.Button
        CorrectButton            matlab.ui.control.Button
        HitentertostopLabel      matlab.ui.control.Label
        ClearButton              matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        Panel_3                  matlab.ui.container.Panel
        SeismicityPanel          matlab.ui.container.Panel
        MagnitudeSlider          matlab.ui.control.Slider
        MagnitudeSliderLabel     matlab.ui.control.Label
        SeismicityTree           matlab.ui.container.CheckBoxTree
        MohoPanel                matlab.ui.container.Panel
        MohoTree                 matlab.ui.container.CheckBoxTree
        Panel_5                  matlab.ui.container.Panel
        TransparencySlider       matlab.ui.control.Slider
        TransparencySliderLabel  matlab.ui.control.Label
        SwitchVolumeData_2       matlab.ui.control.Switch
        VolumeDataDropDown_2     matlab.ui.control.DropDown
        XMin_2                   matlab.ui.control.NumericEditField
        XMax_2                   matlab.ui.control.NumericEditField
        YMin_2                   matlab.ui.control.NumericEditField
        YMax_2                   matlab.ui.control.NumericEditField
        SetLimitsButton_2        matlab.ui.control.Button
        CMaxEditField_2          matlab.ui.control.NumericEditField
        CMinEditField_2          matlab.ui.control.NumericEditField
        ColormapDropDown_2       matlab.ui.control.DropDown
        TomographiesPanel        matlab.ui.container.Panel
        CMaxEditField            matlab.ui.control.NumericEditField
        CLimEditField            matlab.ui.control.NumericEditField
        CLimEditFieldLabel       matlab.ui.control.Label
        ColormapDropDown         matlab.ui.control.DropDown
        XMin                     matlab.ui.control.NumericEditField
        XMax                     matlab.ui.control.NumericEditField
        YMin                     matlab.ui.control.NumericEditField
        YMax                     matlab.ui.control.NumericEditField
        SetLimitsButton          matlab.ui.control.Button
        SwitchVolumeData         matlab.ui.control.Switch
        VolumeDataDropDown       matlab.ui.control.DropDown
        GeophysicalModelGeneratorDataPickerLabel  matlab.ui.control.Label
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % create a structure to hold all the data for the profiles
            app.GMGDataPickerUIFigure.UserData = [];
        end

        % Button pushed function: LoadButton_2
        function OpendatasetMenuSelected(app, event)
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                % disp('User selected Cancel');
                % nothing happens now
            else
                % clear the axes
                % clear all axes and User data
                delete(findall(app.GMGDataPickerUIFigure,'type','annotation'))

                app.GMGDataPickerUIFigure.UserData = [];
              
                % clear the checkbox trees
                app.SeismicityTree.Children.delete;
                app.MohoTree.Children.delete;

                % clear the axes
                cla(app.UIAxes)
                cla(app.UIAxes_2)
                cla(app.UIAxes_3)
                cla(app.UIAxes_4)
                app.UIAxes_4.Visible = 'off'; % turn visibility for screenshot axes off for now

                % load the file into the user data structure
                app.GMGDataPickerUIFigure.UserData = load([path,file]);
                VolData = app.GMGDataPickerUIFigure.UserData.ProfileData.VolData;

                X = VolData.fields.x_profile;
                Y = VolData.depth.val;
                app.GMGDataPickerUIFigure.UserData.FieldData = rmfield(VolData.fields,'x_profile');
                FieldData = app.GMGDataPickerUIFigure.UserData.FieldData;

                % load the vik colormap
                cmap = load("vik.mat");
                app.GMGDataPickerUIFigure.UserData.Colormap.vik = cmap.vik;
                app.GMGDataPickerUIFigure.UserData.Colormap.vikflipped = flipud(cmap.vik);
                % load the oleron colormap
                cmap = load("oleron.mat");
                app.GMGDataPickerUIFigure.UserData.Colormap.oleron = cmap.oleron;

                % set up the dropdown menus for volume data
                VolFieldCell = fields(app.GMGDataPickerUIFigure.UserData.FieldData);
                app.VolumeDataDropDown.Items   = VolFieldCell;
                app.VolumeDataDropDown_2.Items = VolFieldCell;

                % set the dropdown menus for screenshots


                % plot the first volume dataset in all tabs so that we have
                % something to see

                % there is an issue relating to the use of the julia
                % Unitful package, which results in plotting issues
                % this is fixed below
                try
                    app.GMGDataPickerUIFigure.UserData.VolHandle(1) = pcolor(app.UIAxes,X,Y,FieldData.(app.VolumeDataDropDown.Items{1}));
                catch % there may be an issue with the julia Unitful package, so here we try to fix that
                    tmp = cell2mat(FieldData.(app.VolumeDataDropDown.Items{1}));
                    val = zeros(size(tmp));
                    val(:) = [tmp.val];
                    app.GMGDataPickerUIFigure.UserData.VolHandle(1) = pcolor(app.UIAxes,X,Y,val);
                end

                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes,'equal')
                axis(app.UIAxes,'tight')
                shading(app.UIAxes,'flat')
                colorbar(app.UIAxes,"eastoutside")
                colormap(app.UIAxes,app.GMGDataPickerUIFigure.UserData.Colormap.vikflipped)
                app.GMGDataPickerUIFigure.UserData.Title = title(app.UIAxes,[file(1:end-4),' - ',app.VolumeDataDropDown.Items{1}],'Interpreter','none');
                app.SwitchVolumeData.Value = 'On';
                
                % plot the first volume data on top as overlay axes

                % there is an issue relating to the use of the julia
                % Unitful package, which results in plotting issues
                % this is fixed below
                try
                    app.GMGDataPickerUIFigure.UserData.VolHandle(2) = pcolor(app.UIAxes_2,X,Y,FieldData.(app.VolumeDataDropDown_2.Items{1}));
                catch % there may be an issue with the julia Unitful package, so here we try to fix that
                    tmp = cell2mat(FieldData.(app.VolumeDataDropDown.Items{1}));
                    val = zeros(size(tmp));
                    val(:) = [tmp.val];
                    app.GMGDataPickerUIFigure.UserData.VolHandle(2) = pcolor(app.UIAxes_2,X,Y,val);
                end


                app.UIAxes_2.Color = 'none';
                set(app.UIAxes_2,'YDir','normal','box','on','layer','top')
                axis(app.UIAxes_2,'equal')
                axis(app.UIAxes_2,'tight')
                shading(app.UIAxes_2,'flat')
                app.UIAxes_2.InnerPosition = app.UIAxes.InnerPosition;
                app.SwitchVolumeData_2.Value = 'Off';
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData = zeros(size(app.GMGDataPickerUIFigure.UserData.VolHandle(2).CData));
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).FaceAlpha = 'flat';
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaDataMapping = 'none';
                %set(app.UIAxes,'YDir','normal')
                %axis(app.UIAxes,'equal')
                %axis(app.UIAxes,'tight')
                %shading(app.UIAxes,'interp')
                %colorbar(app.UIAxes_2,"eastoutside")
                colormap(app.UIAxes_2,app.GMGDataPickerUIFigure.UserData.Colormap.vikflipped)
                hold(app.UIAxes,'on')
                app.SwitchVolumeData.Value = 'On';

                % plot surface and point data in the third axes on top
                app.UIAxes_3.Color = 'none';
                set(app.UIAxes_3,'YDir','normal','box','on','layer','top')
                axis(app.UIAxes_3,'equal')
                axis(app.UIAxes_3,'tight')
                hold(app.UIAxes_3,'on')
                
                
                % plot the point data --> all of it
                %======================================================
                % also dynamically create the checkbox list
                if ~isempty(app.GMGDataPickerUIFigure.UserData.ProfileData.PointData)
                    ptdatanames = fieldnames(app.GMGDataPickerUIFigure.UserData.ProfileData.PointData);
                    for ipoint = 1:length(ptdatanames)

                        n(ipoint) = uitreenode(app.SeismicityTree);
                        n(ipoint).Text = ptdatanames{ipoint};

                        XP = app.GMGDataPickerUIFigure.UserData.ProfileData.PointData.(ptdatanames{ipoint}).fields.x_profile; % distance along profile is already computed in km!
                        YP = app.GMGDataPickerUIFigure.UserData.ProfileData.PointData.(ptdatanames{ipoint}).depth.val;

                        app.SeismicityTree.Children(ipoint).UserData = plot(app.UIAxes_3,XP,YP,'ko','MarkerSize',8,'MarkerFaceColor','r');
                        %app.GMGDataPickerUIFigure.UserData.PointHandle(ipoint) = plot(app.UIAxes,XP,YP,'ko','MarkerSize',8,'MarkerFaceColor','r');
                    end
                    app.SeismicityTree.CheckedNodes = n;
                    clear n
                end
                % go through the checked nodes again and assign the
                % respective plot handle as UserData
%                 for ipoint = 1:length(app.GMGDataPickerUIFigure.UserData.ProfileData.PointData)
%                     app.SeismicityTree.CheckedNodes(ipoint).UserData = app.GMGDataPickerUIFigure.UserData.PointHandle(ipoint);
%                 end

                % plot the surface/Moho data --> all of it 
                %======================================================
                iplot = 1;
                if ~isempty(app.GMGDataPickerUIFigure.UserData.ProfileData.SurfData)
                    sfdatanames = fieldnames(app.GMGDataPickerUIFigure.UserData.ProfileData.SurfData);
                    for isurf = 1:length(sfdatanames)

                        % see if the moho data has anything else than NaN, only
                        % then plot it
                        if any(~isnan(app.GMGDataPickerUIFigure.UserData.ProfileData.SurfData.(sfdatanames{isurf}).depth.val))

                            n(iplot) = uitreenode(app.MohoTree);
                            n(iplot).Text = sfdatanames{isurf};

                            XP = app.GMGDataPickerUIFigure.UserData.ProfileData.PointData.(ptdatanames{ipoint}).fields.x_profile; % distance along profile is already computed in km!
                            XS = app.GMGDataPickerUIFigure.UserData.ProfileData.SurfData.(sfdatanames{isurf}).fields.x_profile;
                            YS = app.GMGDataPickerUIFigure.UserData.ProfileData.SurfData.(sfdatanames{isurf}).depth.val;

                            app.MohoTree.Children(iplot).UserData.borderline = plot(app.UIAxes_3,XS,YS,'w-','LineWidth',4);
                            app.MohoTree.Children(iplot).UserData.centerline = plot(app.UIAxes_3,XS,YS,'k-','LineWidth',2);
                            %centerline(iplot) = plot(app.UIAxes,X,Y,'k-','LineWidth',2);

                            %hlink(iplot) = linkprop([app.MohoTree.Children(iplot).UserData,centerline(iplot)],{'XData','YData','Visible'});

                            iplot = iplot + 1;
                        end
                    end
                    app.MohoTree.CheckedNodes = n;
                end
                % set xlim to the tomography data
                xlim(app.UIAxes,[min(X(:)) max(X(:))])
                xlim(app.UIAxes_2,[min(X(:)) max(X(:))])
                xlim(app.UIAxes_3,[min(X(:)) max(X(:))])
                
                ylim(app.UIAxes,[min(Y(:)) 0])
                ylim(app.UIAxes_2,[min(Y(:)) 0])
                ylim(app.UIAxes_3,[min(Y(:)) 0])


                % also set the correct numbers in the respective text
                % fields
                app.XMin.Value = min(X(:));
                app.XMax.Value = max(X(:));
                app.YMin.Value = min(Y(:));
                app.YMax.Value  = 0;
                
                app.XMin_2.Value = app.XMin.Value;
                app.XMax_2.Value = app.XMax.Value;
                app.YMin_2.Value = app.YMin.Value;
                app.YMax_2.Value = app.YMax.Value;

                app.UIAxes_2.InnerPosition = app.UIAxes.InnerPosition;
                app.UIAxes_3.InnerPosition = app.UIAxes.InnerPosition;

                % switch off interactive components (e.g. zooming with
                % scrolling etc.)
                disableDefaultInteractivity(app.UIAxes)
                disableDefaultInteractivity(app.UIAxes_2)
                disableDefaultInteractivity(app.UIAxes_3)

                % add annotation of starting and ending point
                lon_start = fieldnames(app.GMGDataPickerUIFigure.UserData.ProfileData.start_lonlat);lon_start = lon_start{1};
                lat_start = app.GMGDataPickerUIFigure.UserData.ProfileData.start_lonlat.(lon_start);lat_start = num2str(lat_start);
                
                lon_end = fieldnames(app.GMGDataPickerUIFigure.UserData.ProfileData.end_lonlat);lon_end = lon_end{1};
                lat_end = app.GMGDataPickerUIFigure.UserData.ProfileData.end_lonlat.(lon_end);lat_end = num2str(lat_end);
                
                app.GMGDataPickerUIFigure.UserData.AnnotStart  = annotation(app.GMGDataPickerUIFigure,'textbox',[0.25 0.96 0.025 0.025],'String',['lon: ',lon_start,sprintf('\nlat: '),num2str(lat_start)],'FontSize',16,'FitBoxToText','on');
                app.GMGDataPickerUIFigure.UserData.AnnotEnd  = annotation(app.GMGDataPickerUIFigure,'textbox',[0.9 0.96 0.025 0.025],'String',['lon: ',lon_end,sprintf('\nlat: '),num2str(lat_end)],'FontSize',16,'FitBoxToText','on');

                disp(['User selected ', fullfile(path,file)]);
            end
        end

        % Callback function
        function LoadDataButtonPushed(app, event)
            OpendatasetMenuSelected(app, event)
        end

        % Value changed function: VolumeDataDropDown
        function VolumeDataDropDownValueChanged(app, event)
            value = app.VolumeDataDropDown.Value;
            FieldData = app.GMGDataPickerUIFigure.UserData.FieldData;
            try
                app.GMGDataPickerUIFigure.UserData.VolHandle(1).CData = FieldData.(value);
            catch % there may be an issue with the julia Unitful package, so here we try to fix that
                tmp = cell2mat(FieldData.(value));
                val = zeros(size(tmp));
                val(:) = [tmp.val];
                app.GMGDataPickerUIFigure.UserData.VolHandle(1).CData = val;
            end
    
            tmpStr = split(app.GMGDataPickerUIFigure.UserData.Title.String,'-');

            app.GMGDataPickerUIFigure.UserData.Title = title(app.UIAxes,[tmpStr{1},' - ',value],'Interpreter','none');

        end

        % Value changed function: CLimEditField
        function CLimEditFieldValueChanged(app, event)
            value = app.CLimEditField.Value;
            tmp = version("-release");
            if str2double(tmp(1:4))<2022
                caxis(app.UIAxes,[value max(app.UIAxes.CLim)])
            else
                clim(app.UIAxes,[value max(app.UIAxes.CLim)])
            end

        end

        % Value changed function: CMaxEditField
        function CMaxEditFieldValueChanged(app, event)
            value = app.CMaxEditField.Value;
            tmp = version("-release");
            if str2double(tmp(1:4))<2022
                caxis(app.UIAxes,[min(app.UIAxes.CLim) value])
            else
                clim(app.UIAxes,[min(app.UIAxes.CLim) value])
            end
        end

        % Button pushed function: SetLimitsButton
        function SetLimitsButtonPushed(app, event)
            app.UIAxes.XLim = [app.XMin.Value app.XMax.Value];
            app.UIAxes.YLim = [app.YMin.Value app.YMax.Value];

            app.UIAxes_2.XLim = [app.XMin.Value app.XMax.Value];
            app.UIAxes_2.YLim = [app.YMin.Value app.YMax.Value];
            app.UIAxes_2.InnerPosition = app.UIAxes.InnerPosition;

            app.UIAxes_3.XLim = [app.XMin.Value app.XMax.Value];
            app.UIAxes_3.YLim = [app.YMin.Value app.YMax.Value];
            app.UIAxes_3.InnerPosition = app.UIAxes.InnerPosition;

            app.XMin_2.Value = app.XMin.Value;
            app.XMax_2.Value = app.XMax.Value;
            app.YMin_2.Value = app.YMin.Value;
            app.YMax_2.Value = app.YMax.Value;

        end

        % Value changed function: SwitchVolumeData
        function SwitchVolumeDataValueChanged(app, event)
            value = app.SwitchVolumeData.Value;
            if strcmp(value,'On')
                app.GMGDataPickerUIFigure.UserData.VolHandle(1).Visible = true;
            else
                app.GMGDataPickerUIFigure.UserData.VolHandle(1).Visible = false;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.GMGDataPickerUIFigure.HandleVisibility;        % Current status
            app.GMGDataPickerUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.GMGDataPickerUIFigure)       % Make fig current

            % clear all previous picks
            if isfield(app.GMGDataPickerUIFigure.UserData,'hPick1')
                app.GMGDataPickerUIFigure.UserData.PickedX = [];
                app.GMGDataPickerUIFigure.UserData.PickedZ = [];
                app.GMGDataPickerUIFigure.UserData.hPick1.XData = [];
                app.GMGDataPickerUIFigure.UserData.hPick1.YData = [];
                app.GMGDataPickerUIFigure.UserData.hPick2.XData = [];
                app.GMGDataPickerUIFigure.UserData.hPick2.YData = [];
            end
            % pick

            [x,y] = ginputc('MarkerSize',5,'ShowPoints',true); % collect as many points as you wish, hit enter to stop
            app.GMGDataPickerUIFigure.UserData.PickedX = x;
            app.GMGDataPickerUIFigure.UserData.PickedZ = y;
            % the picked points disappear after the function has been
            % executed, so here we plot it again
            app.GMGDataPickerUIFigure.UserData.hPick1 = plot(app.UIAxes_3,app.GMGDataPickerUIFigure.UserData.PickedX,app.GMGDataPickerUIFigure.UserData.PickedZ,'w-','LineWidth',4);
            app.GMGDataPickerUIFigure.UserData.hPick2 = plot(app.UIAxes_3,app.GMGDataPickerUIFigure.UserData.PickedX,app.GMGDataPickerUIFigure.UserData.PickedZ,'v-','MarkerFaceColor','k','MarkerEdgeColor','w','Color','k','MarkerSize',8,'LineWidth',2);
            app.GMGDataPickerUIFigure.HandleVisibility = fhv;        % return original state

        end

        % Callback function
        function StopPickingButtonPushed(app, event)
            app.GMGDataPickerUIFigure.UserData.KeepPicking = false;
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            % clear all axes and User data
            app.GMGDataPickerUIFigure.UserData = [];
            app.SeismicityTree.Children.delete;
            app.MohoTree.Children.delete;
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
            delete(findall(app.GMGDataPickerUIFigure,'type','annotation'))

            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % open save file dialogue
            [FILE,PATH] = uiputfile('*.txt');
            X = app.GMGDataPickerUIFigure.UserData.PickedX(~isnan(app.GMGDataPickerUIFigure.UserData.PickedX));
            Z = app.GMGDataPickerUIFigure.UserData.PickedZ(~isnan(app.GMGDataPickerUIFigure.UserData.PickedX));
            
            % interpolate lat/lon to the picked points
            Lon = app.GMGDataPickerUIFigure.UserData.ProfileData.VolData.lon.val;
            Lat = app.GMGDataPickerUIFigure.UserData.ProfileData.VolData.lat.val;
            LON = interp1(app.GMGDataPickerUIFigure.UserData.ProfileData.VolData.fields.x_profile(:,1),Lon(:,1),X);
            LAT = interp1(app.GMGDataPickerUIFigure.UserData.ProfileData.VolData.fields.x_profile(:,1),Lat(:,1),X);

            if FILE~=0
                % save the picked data data as ascii
                T = table(X(:),Z(:),LON(:),LAT(:));
                header={'X','depth','Lon','Lat'};
                T.Properties.VariableNames = header;
                writetable(T,[PATH,FILE])
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.GMGDataPickerUIFigure.UserData.PickedX = [];
            app.GMGDataPickerUIFigure.UserData.PickedZ = [];
            app.GMGDataPickerUIFigure.UserData.hPick1.XData = [];
            app.GMGDataPickerUIFigure.UserData.hPick1.YData = [];
            app.GMGDataPickerUIFigure.UserData.hPick2.XData = [];
            app.GMGDataPickerUIFigure.UserData.hPick2.YData = [];
            app.GMGDataPickerUIFigure.UserData.hComp1.XData = [];
            app.GMGDataPickerUIFigure.UserData.hComp2.XData = [];
            app.GMGDataPickerUIFigure.UserData.hComp1.YData = [];
            app.GMGDataPickerUIFigure.UserData.hComp2.YData = [];

            if isfield(app.GMGDataPickerUIFigure.UserData,'hComp1')
                app.GMGDataPickerUIFigure.UserData.hComp1.XData = [];
                app.GMGDataPickerUIFigure.UserData.hComp1.YData = [];
                app.GMGDataPickerUIFigure.UserData.hComp2.XData = [];
                app.GMGDataPickerUIFigure.UserData.hComp2.YData = [];
            end

        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            % load profile file -> should be mat or text file
            [FILE,PATH] = uigetfile("*.txt");

            if FILE~=0
                T = readtable([PATH,FILE]);
                % plot the picks to compare
                app.GMGDataPickerUIFigure.UserData.hComp1 = plot(app.UIAxes_3,T.X,T.depth,'w-','LineWidth',4);
                app.GMGDataPickerUIFigure.UserData.hComp2 = plot(app.UIAxes_3,T.X,T.depth,'r-','LineWidth',2);
            end
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            if strcmp(value,'vik')
                colormap(app.UIAxes,app.GMGDataPickerUIFigure.UserData.Colormap.vik);
            elseif strcmp(value,'vikflipped')
                colormap(app.UIAxes,app.GMGDataPickerUIFigure.UserData.Colormap.vikflipped);
            else
                colormap(app.UIAxes,value)
            end

        end

        % Callback function
        function SwitchSurfaceDataValueChanged(app, event)
            value = app.SwitchSurfaceData.Value;
            if strcmp(value,'On')
                [app.GMGDataPickerUIFigure.UserData.SurfHandle.Visible] = deal(true);
            else
                [app.GMGDataPickerUIFigure.UserData.SurfHandle.Visible] = deal(false);
            end
        end

        % Callback function
        function SwitchPointDataValueChanged(app, event)
            value = app.SwitchPointData.Value;
            if strcmp(value,'On')
                [app.GMGDataPickerUIFigure.UserData.PointHandle.Visible] = deal(true);
            else
                [app.GMGDataPickerUIFigure.UserData.PointHandle.Visible] = deal(false);
            end
        end

        % Value changed function: SwitchVolumeData_2
        function SwitchVolumeData_2ValueChanged(app, event)
            value = app.SwitchVolumeData_2.Value;
            if strcmp(value,'On')
                %app.GMGDataPickerUIFigure.UserData.VolHandle(2).Visible = true;
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData = ones(size(app.GMGDataPickerUIFigure.UserData.VolHandle(2).CData));
            else
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData = zeros(size(app.GMGDataPickerUIFigure.UserData.VolHandle(2).CData));
            end
            %app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaDataMapping = 'none';
        end

        % Value changed function: VolumeDataDropDown_2
        function VolumeDataDropDown_2ValueChanged(app, event)
            value = app.VolumeDataDropDown_2.Value;
            FieldData = app.GMGDataPickerUIFigure.UserData.FieldData;
            try
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).CData = FieldData.(value);
            catch % there may be an issue with the julia Unitful package, so here we try to fix that
                tmp = cell2mat(FieldData.(value));
                val = zeros(size(tmp));
                val(:) = [tmp.val];
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).CData = val;
            end


            %app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaDataMapping = 'none';
        end

        % Value changed function: CMinEditField_2
        function CMinEditField_2ValueChanged(app, event)
            value = app.CMinEditField_2.Value;
            tmp = version("-release");
            if str2double(tmp(1:4))<2022
                caxis(app.UIAxes_2,[value max(clim(app.UIAxes_2))])
            else
                clim(app.UIAxes_2,[value max(clim(app.UIAxes_2))])
            end
        end

        % Value changed function: CMaxEditField_2
        function CMaxEditField_2ValueChanged(app, event)
            value = app.CMaxEditField_2.Value;
            tmp = version("-release");
            if str2double(tmp(1:4))<2022
                caxis(app.UIAxes_2,[min(app.UIAxes_2.CLim) value])
            else
                clim(app.UIAxes_2,[min(app.UIAxes_2.CLim) value])
            end
        end

        % Value changed function: ColormapDropDown_2
        function ColormapDropDown_2ValueChanged(app, event)
            value = app.ColormapDropDown_2.Value;
            if strcmp(value,'vik')
                colormap(app.UIAxes_2,app.GMGDataPickerUIFigure.UserData.Colormap.vik)
            elseif strcmp(value,'vikflipped')
                colormap(app.UIAxes_2,app.GMGDataPickerUIFigure.UserData.Colormap.vikflipped)
            else
                colormap(app.UIAxes_2,value)
            end

        end

        % Button pushed function: SetLimitsButton_2
        function SetLimitsButton_2Pushed(app, event)
            ind = app.GMGDataPickerUIFigure.UserData.VolHandle(2).XData <= app.XMax_2.Value & app.GMGDataPickerUIFigure.UserData.VolHandle(2).XData >= app.XMin_2.Value & app.GMGDataPickerUIFigure.UserData.VolHandle(2).YData >= app.YMin_2.Value & app.GMGDataPickerUIFigure.UserData.VolHandle(2).YData <= app.YMax_2.Value;
            app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData(ind) = app.TransparencySlider.Value;
            app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData(~ind) = 0;
            %app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaDataMapping = 'none';
        end

        % Value changed function: TransparencySlider
        function TransparencySliderValueChanged(app, event)
            value = app.TransparencySlider.Value;
            if any(app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData(:) > 0)
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData(app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData ~= 0) = value;
            else
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData(:) = value;
            end
        end

        % Value changing function: TransparencySlider
        function TransparencySliderValueChanging(app, event)
            changingValue = event.Value;
            if any(app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData(:) > 0)
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData(app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData ~= 0) = changingValue;
            else
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData(:) = changingValue;
            end
        end

        % Callback function: MohoTree
        function MohoTreeCheckedNodesChanged(app, event)
            allnodes = findall(app.MohoTree,"Type","uitreenode");
            ischecked = ismember(allnodes,app.MohoTree.CheckedNodes);
            % only the data from checked boxes is visible
            for ibox = 1:length(allnodes)
                if ischecked(ibox)
                    allnodes(ibox).UserData.borderline.Visible ="on";
                    allnodes(ibox).UserData.centerline.Visible ="on";
                else
                    allnodes(ibox).UserData.borderline.Visible ="off";
                    allnodes(ibox).UserData.centerline.Visible ="off";
                end
            end
        end

        % Callback function: SeismicityTree
        function SeismicityTreeCheckedNodesChanged(app, event)

            allnodes = findall(app.SeismicityTree,"Type","uitreenode");
            ischecked = ismember(allnodes,app.SeismicityTree.CheckedNodes);
            % only the data from checked boxes is visible
            for ibox = 1:length(allnodes)
                if ischecked(ibox)
                   allnodes(ibox).UserData.Visible = true; 
                else
                   allnodes(ibox).UserData.Visible = false;
                end
            end
        end

        % Button pushed function: CorrectButton
        function CorrectButtonPushed(app, event)
            % pick another point close to the existing one, this one is the replaced
            fhv = app.GMGDataPickerUIFigure.HandleVisibility;        % Current status
            app.GMGDataPickerUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.GMGDataPickerUIFigure)       % Make fig current
            
            % pick
            [x_corr,y_corr] = ginputc(1,'MarkerSize',5,'ShowPoints',true); % pick one single point

            [~,ind] = min((app.GMGDataPickerUIFigure.UserData.PickedX-x_corr).^2+(app.GMGDataPickerUIFigure.UserData.PickedZ-y_corr).^2);


            app.GMGDataPickerUIFigure.UserData.hPick1.XData(ind) = x_corr;
            app.GMGDataPickerUIFigure.UserData.hPick1.YData(ind) = y_corr;
            app.GMGDataPickerUIFigure.UserData.hPick2.XData(ind) = x_corr;
            app.GMGDataPickerUIFigure.UserData.hPick2.YData(ind) = y_corr;

            app.GMGDataPickerUIFigure.UserData.PickedX(ind) = x_corr;
            app.GMGDataPickerUIFigure.UserData.PickedZ(ind) = y_corr;


%             app.GMGDataPickerUIFigure.UserData.PickedX = x;
%             app.GMGDataPickerUIFigure.UserData.PickedZ = y;
            % the picked points disappear after the function has been
            % executed, so here we plot it again
%             app.GMGDataPickerUIFigure.UserData.hPick = plot3(app.UIAxes,app.GMGDataPickerUIFigure.UserData.PickedX,app.GMGDataPickerUIFigure.UserData.PickedZ,110e3*ones(size(app.GMGDataPickerUIFigure.UserData.PickedX)),'kv-','MarkerFaceColor','w','MarkerEdgeColor','k','MarkerSize',8,'LineWidth',2);
            app.GMGDataPickerUIFigure.HandleVisibility = fhv;        % return original state

        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            % continue picking
            fhv = app.GMGDataPickerUIFigure.HandleVisibility;        % Current status
            app.GMGDataPickerUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.GMGDataPickerUIFigure)       % Make fig current
            
            % pick
            [x,y] = ginputc('MarkerSize',5,'ShowPoints',true); % pick as many as needed, finalize by hitting enter
            

            app.GMGDataPickerUIFigure.UserData.hPick1.XData = [app.GMGDataPickerUIFigure.UserData.hPick1.XData x'];
            app.GMGDataPickerUIFigure.UserData.hPick1.YData = [app.GMGDataPickerUIFigure.UserData.hPick1.YData y'];
            app.GMGDataPickerUIFigure.UserData.hPick2.XData = [app.GMGDataPickerUIFigure.UserData.hPick2.XData x'];
            app.GMGDataPickerUIFigure.UserData.hPick2.YData = [app.GMGDataPickerUIFigure.UserData.hPick2.YData y'];

            app.GMGDataPickerUIFigure.UserData.PickedX = app.GMGDataPickerUIFigure.UserData.hPick1.XData;
            app.GMGDataPickerUIFigure.UserData.PickedZ = app.GMGDataPickerUIFigure.UserData.hPick1.YData;

            app.GMGDataPickerUIFigure.HandleVisibility = fhv;        % return original state

        end

        % Button pushed function: SavescreenshotButton
        function SavescreenshotButtonPushed(app, event)
            % load topo file
            [FILE,PATH] = uiputfile("*.png");
            
            if FILE~=0
                % write path and file in edit field
                exportgraphics(app.GMGDataPickerUIFigure,[PATH,FILE],'Resolution',300)
            end

        end

        % Selection changed function: MohoTree
        function MohoTreeSelectionChanged(app, event)
            selectedNodes = app.MohoTree.SelectedNodes;
            
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            % load profile file -> should be mat or text file
            [FILE,PATH] = uigetfile("*.txt");

            if FILE~=0
                T = readtable([PATH,FILE]);
                % plot the picks to compare and assign to user data
                if isfield(app.GMGDataPickerUIFigure.UserData,'hPick1')
                    app.GMGDataPickerUIFigure.UserData.hPick1.XData = T.X;
                    app.GMGDataPickerUIFigure.UserData.hPick1.YData = T.depth;
                    app.GMGDataPickerUIFigure.UserData.hPick2.XData = T.X;
                    app.GMGDataPickerUIFigure.UserData.hPick2.YData = T.depth;

                    app.GMGDataPickerUIFigure.UserData.PickedX = T.X;
                    app.GMGDataPickerUIFigure.UserData.PickedZ = T.depth;
                else
                    app.GMGDataPickerUIFigure.UserData.PickedX = T.X;
                    app.GMGDataPickerUIFigure.UserData.PickedZ = T.depth;
                    app.GMGDataPickerUIFigure.UserData.hPick1 = plot(app.UIAxes_3,app.GMGDataPickerUIFigure.UserData.PickedX,app.GMGDataPickerUIFigure.UserData.PickedZ,'w-','LineWidth',4);
                    app.GMGDataPickerUIFigure.UserData.hPick2 = plot(app.UIAxes_3,app.GMGDataPickerUIFigure.UserData.PickedX,app.GMGDataPickerUIFigure.UserData.PickedZ,'v-','MarkerFaceColor','k','MarkerEdgeColor','w','MarkerSize',8,'LineWidth',2);
                end

            end
        end

        % Value changed function: MagnitudeSlider
        function MagnitudeSliderValueChanged(app, event)
            value = app.MagnitudeSlider.Value;
            ptdatanames = fieldnames(app.GMGDataPickerUIFigure.UserData.ProfileData.PointData);

            for ipoint = 1:length(app.SeismicityTree.Children)
                %XP = app.GMGDataPickerUIFigure.UserData.ProfileData.PointData{ipoint}.fields.x_profile; % distance along profile is already computed in km!
                %YP = app.GMGDataPickerUIFigure.UserData.ProfileData.PointData{ipoint}.depth.val;
                
                XP = app.GMGDataPickerUIFigure.UserData.ProfileData.PointData.(ptdatanames{ipoint}).fields.x_profile; % distance along profile is already computed in km!
                YP = app.GMGDataPickerUIFigure.UserData.ProfileData.PointData.(ptdatanames{ipoint}).depth.val;
                mag = app.GMGDataPickerUIFigure.UserData.ProfileData.PointData.(ptdatanames{ipoint}).fields.Magnitude;

                
                if iscell(mag),mag=cell2mat(mag);end
                ind = mag>value;
                app.SeismicityTree.Children(ipoint).UserData.XData =  XP(ind);
                app.SeismicityTree.Children(ipoint).UserData.YData =  YP(ind);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GMGDataPickerUIFigure = uifigure('Visible', 'off');
            app.GMGDataPickerUIFigure.Name = 'GMGDataPicker';
            app.UIAxes = uiaxes(app.GMGDataPickerUIFigure);
            xlabel(app.UIAxes, 'x [km]')
            ylabel(app.UIAxes, 'depth [km]')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'turbo')
            app.UIAxes_4 = uiaxes(app.GMGDataPickerUIFigure);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_2 = uiaxes(app.GMGDataPickerUIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            colormap(app.UIAxes_2, 'turbo')
            app.UIAxes_3 = uiaxes(app.GMGDataPickerUIFigure);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            colormap(app.UIAxes_3, 'turbo')
            app.GeophysicalModelGeneratorDataPickerLabel = uilabel(app.GMGDataPickerUIFigure);
            app.GeophysicalModelGeneratorDataPickerLabel.Text = 'GeophysicalModelGenerator Data Picker';
            app.Panel_3 = uipanel(app.GMGDataPickerUIFigure);
            app.TomographiesPanel = uipanel(app.Panel_3);
            app.TomographiesPanel.Title = 'Tomographies';
            app.VolumeDataDropDown = uidropdown(app.TomographiesPanel);
            app.VolumeDataDropDown.ValueChangedFcn = createCallbackFcn(app, @VolumeDataDropDownValueChanged, true);
            app.SwitchVolumeData = uiswitch(app.TomographiesPanel, 'slider');
            app.SwitchVolumeData.ValueChangedFcn = createCallbackFcn(app, @SwitchVolumeDataValueChanged, true);
            app.SetLimitsButton = uibutton(app.TomographiesPanel, 'push');
            app.SetLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @SetLimitsButtonPushed, true);
            app.SetLimitsButton.Text = 'Set Limits';
            app.YMax = uieditfield(app.TomographiesPanel, 'numeric');
            app.YMin = uieditfield(app.TomographiesPanel, 'numeric');
            app.XMax = uieditfield(app.TomographiesPanel, 'numeric');
            app.XMin = uieditfield(app.TomographiesPanel, 'numeric');
            app.ColormapDropDown = uidropdown(app.TomographiesPanel);
            app.ColormapDropDown.Items = {'turbo', 'hot', 'spring', 'summer', 'autumn', 'winter', 'gray', 'vik', 'vikflipped'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'vikflipped';
            app.CLimEditFieldLabel = uilabel(app.TomographiesPanel);
            app.CLimEditFieldLabel.Text = 'CLim';
            app.CLimEditField = uieditfield(app.TomographiesPanel, 'numeric');
            app.CLimEditField.ValueChangedFcn = createCallbackFcn(app, @CLimEditFieldValueChanged, true);
            app.CMaxEditField = uieditfield(app.TomographiesPanel, 'numeric');
            app.CMaxEditField.ValueChangedFcn = createCallbackFcn(app, @CMaxEditFieldValueChanged, true);
            app.CMaxEditField.Value = 1;
            app.Panel_5 = uipanel(app.Panel_3);
            app.ColormapDropDown_2 = uidropdown(app.Panel_5);
            app.ColormapDropDown_2.Items = {'turbo', 'hot', 'spring', 'summer', 'autumn', 'winter', 'gray', 'vik', 'vikflipped'};
            app.ColormapDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDown_2ValueChanged, true);
            app.ColormapDropDown_2.Value = 'vikflipped';
            app.CMinEditField_2 = uieditfield(app.Panel_5, 'numeric');
            app.CMinEditField_2.ValueChangedFcn = createCallbackFcn(app, @CMinEditField_2ValueChanged, true);
            app.CMaxEditField_2 = uieditfield(app.Panel_5, 'numeric');
            app.CMaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @CMaxEditField_2ValueChanged, true);
            app.CMaxEditField_2.Value = 1;
            app.SetLimitsButton_2 = uibutton(app.Panel_5, 'push');
            app.SetLimitsButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetLimitsButton_2Pushed, true);
            app.SetLimitsButton_2.Text = 'Set Limits';
            app.YMax_2 = uieditfield(app.Panel_5, 'numeric');
            app.YMin_2 = uieditfield(app.Panel_5, 'numeric');
            app.XMax_2 = uieditfield(app.Panel_5, 'numeric');
            app.XMin_2 = uieditfield(app.Panel_5, 'numeric');
            app.VolumeDataDropDown_2 = uidropdown(app.Panel_5);
            app.VolumeDataDropDown_2.ValueChangedFcn = createCallbackFcn(app, @VolumeDataDropDown_2ValueChanged, true);
            app.SwitchVolumeData_2 = uiswitch(app.Panel_5, 'slider');
            app.SwitchVolumeData_2.ValueChangedFcn = createCallbackFcn(app, @SwitchVolumeData_2ValueChanged, true);
            app.TransparencySliderLabel = uilabel(app.Panel_5);
            app.TransparencySliderLabel.Text = 'Transparency';
            app.TransparencySlider = uislider(app.Panel_5);
            app.TransparencySlider.Limits = [0 1];
            app.TransparencySlider.MajorTicks = [0 0.5 1];
            app.TransparencySlider.ValueChangedFcn = createCallbackFcn(app, @TransparencySliderValueChanged, true);
            app.TransparencySlider.ValueChangingFcn = createCallbackFcn(app, @TransparencySliderValueChanging, true);
            app.MohoPanel = uipanel(app.Panel_3);
            app.MohoPanel.Title = 'Moho data';
            app.MohoTree = uitree(app.MohoPanel, 'checkbox');
            app.MohoTree.SelectionChangedFcn = createCallbackFcn(app, @MohoTreeSelectionChanged, true);
            % Assign Checked Nodes
            app.MohoTree.CheckedNodesChangedFcn = createCallbackFcn(app, @MohoTreeCheckedNodesChanged, true);
            app.SeismicityPanel = uipanel(app.Panel_3);
            app.SeismicityPanel.Title = 'Seismicity';
            app.SeismicityTree = uitree(app.SeismicityPanel, 'checkbox');
            % Assign Checked Nodes
            app.SeismicityTree.CheckedNodesChangedFcn = createCallbackFcn(app, @SeismicityTreeCheckedNodesChanged, true);
            app.MagnitudeSliderLabel = uilabel(app.SeismicityPanel);
            app.MagnitudeSliderLabel.Text = 'Magnitude';
            app.MagnitudeSlider = uislider(app.SeismicityPanel);
            app.MagnitudeSlider.Limits = [0 7];
            app.MagnitudeSlider.ValueChangedFcn = createCallbackFcn(app, @MagnitudeSliderValueChanged, true);
            app.MagnitudeSlider.Value = 2;
            app.PickingPanel = uipanel(app.GMGDataPickerUIFigure);
            app.PickingPanel.Title = 'Picking';
            app.StartButton = uibutton(app.PickingPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.PickingPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ClearButton = uibutton(app.PickingPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.HitentertostopLabel = uilabel(app.PickingPanel);
            app.HitentertostopLabel.Text = 'Hit "enter" to stop';
            app.CorrectButton = uibutton(app.PickingPanel, 'push');
            app.CorrectButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectButtonPushed, true);
            app.CorrectButton.Text = 'Correct';
            app.ContinueButton = uibutton(app.PickingPanel, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.CompareButton = uibutton(app.PickingPanel, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.LoadButton_3 = uibutton(app.PickingPanel, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Load';
            app.ProfiledataPanel = uipanel(app.GMGDataPickerUIFigure);
            app.ProfiledataPanel.Title = 'Profile data';
            app.LoadButton_2 = uibutton(app.ProfiledataPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpendatasetMenuSelected, true);
            app.LoadButton_2.Text = 'Load';
            app.ClearButton_2 = uibutton(app.ProfiledataPanel, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.SavescreenshotButton = uibutton(app.ProfiledataPanel, 'push');
            app.SavescreenshotButton.ButtonPushedFcn = createCallbackFcn(app, @SavescreenshotButtonPushed, true);
            app.SavescreenshotButton.Text = 'Save screenshot';
            app.ScreenshotsPanel = uipanel(app.GMGDataPickerUIFigure);
            app.ScreenshotsPanel.Title = 'Screenshots';
            app.DropDown = uidropdown(app.ScreenshotsPanel);
            app.Switch = uiswitch(app.ScreenshotsPanel, 'slider');
            % Show the figure after all components are created
            app.GMGDataPickerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GMGDataPicker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GMGDataPickerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GMGDataPickerUIFigure)
        end
    end
end