classdef ProfileSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LoadListButton             matlab.ui.control.Button
        ClearListButton            matlab.ui.control.Button
        SaveListButton             matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        DatasetLocationEditField   matlab.ui.control.EditField
        AddtoListButton            matlab.ui.control.Button
        DatasetnameEditField       matlab.ui.control.EditField
        DatasetnameEditFieldLabel  matlab.ui.control.Label
        DatasetlocationButton      matlab.ui.control.Button
        DatasettypeDropDown        matlab.ui.control.DropDown
        DatasettypeLabel           matlab.ui.control.Label
        SetLimitsButton            matlab.ui.control.Button
        LatMin                     matlab.ui.control.EditField
        LatMax                     matlab.ui.control.EditField
        LonMax                     matlab.ui.control.EditField
        LonMin                     matlab.ui.control.EditField
        ClearprofilesButton        matlab.ui.control.Button
        SaveprofilesButton         matlab.ui.control.Button
        PicknewprofileButton       matlab.ui.control.Button
        ModifyProfilesButton       matlab.ui.control.Button
        LoadProfilesButton         matlab.ui.control.Button
        LoadTopoButton             matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load colormap
            load('oleron.mat','oleron')

            % load Alpine topography as default
            %[A,R] = readgeoraster('./ETOPO1_Alps.tiff');
            % plot
            %geoshow(app.UIAxes,double(A),R,'DisplayType','surface')
            %hold(app.UIAxes,"on")
            colormap(app.UIAxes,oleron)

            tmp = version("-release");
            if str2double(tmp(1:4))<2022
                caxis(app.UIAxes,[-4 4]*1e3)
            else
                clim(app.UIAxes,[-4 4]*1e3)
            end

            %set(app.UIAxes,'box','on','layer','top','XLim',R.LongitudeLimits,'YLim',R.LatitudeLimits)
            %daspect(app.UIAxes,[1.65 1 1]) % this should approximately the scaling factor for Mercator so that our angles are ok
            %app.LonMin.Value = num2str(R.LongitudeLimits(1));
            %app.LonMax.Value = num2str(R.LongitudeLimits(2));
            %app.LatMin.Value = num2str(R.LatitudeLimits(1));
            %app.LatMax.Value = num2str(R.LatitudeLimits(2));

            % create a structure to hold all the data for the profiles
            app.UIFigure.UserData = struct("num",[],"start",[],"end",[],... % these are the actual values
                "start_handle",[],"end_handle",[],"line_handle",[],"text_handle",[]); % these are plotting handles for interactive data modification
        end

        % Button pushed function: LoadTopoButton
        function LoadTopoButtonPushed(app, event)
            % load colormap
            load('oleron.mat','oleron')

            % load topo file
            [FILE,PATH] = uigetfile("*.tiff");

            if FILE~=0
                % read the data
                [A,R] = readgeoraster([PATH,FILE]);
                % plot
                geoshow(app.UIAxes,double(A),R,'DisplayType','surface')
                hold(app.UIAxes,"on")
                colormap(app.UIAxes,oleron)
                tmp = version("-release");
                if str2double(tmp(1:4))<2022
                    caxis(app.UIAxes,[-4 4]*1e3)
                else
                    clim(app.UIAxes,[-4 4]*1e3)
                end

                set(app.UIAxes,'box','on','layer','top','XLim',R.LongitudeLimits,'YLim',R.LatitudeLimits)
                daspect(app.UIAxes,[1.65 1 1]) % this should approximately the scaling factor for Mercator so that our angles are ok
                app.LonMin.Value = num2str(R.LongitudeLimits(1));
                app.LonMax.Value = num2str(R.LongitudeLimits(2));
                app.LatMin.Value = num2str(R.LatitudeLimits(1));
                app.LatMax.Value = num2str(R.LatitudeLimits(2));
            end
        end

        % Button pushed function: LoadProfilesButton
        function LoadProfilesButtonPushed(app, event)
            % load profile file -> should be mat or text file
            app.UIFigure.Visible = 'off';
            [FILE,PATH] = uigetfile("*.txt");
            app.UIFigure.Visible = 'on';
            if FILE~=0
                T = readtable([PATH,FILE]);

                % plot the profiles along with their numbers
                for iprofile=1:size(T,1)
                    num = T.Num(iprofile);
                    hold(app.UIAxes,'on')
                    h_line  = plot3(app.UIAxes,[T.LonStart(iprofile) T.LonEnd(iprofile)], [T.LatStart(iprofile) T.LatEnd(iprofile)],[10e3 10e3],'r-','LineWidth',2);
                    h_start = plot3(app.UIAxes,T.LonStart(iprofile),T.LatStart(iprofile),10e3,'ro','MarkerFaceColor','r');
                    h_end   = plot3(app.UIAxes,T.LonEnd(iprofile),T.LatEnd(iprofile),10e3,'ro','MarkerFaceColor','r');
                    h_text1  = text(app.UIAxes,T.LonEnd(iprofile),T.LatEnd(iprofile),10e3,[' A'],'Color','red','FontSize',14);
                    h_text2  = text(app.UIAxes,T.LonStart(iprofile),T.LatStart(iprofile),10e3,[' B'],'Color','red','FontSize',14);
                    h_text3  = text(app.UIAxes,0.5*(T.LonEnd(iprofile)+T.LonStart(iprofile)),0.5*(T.LatStart(iprofile)+T.LatEnd(iprofile)),10e3,[' ',num2str(T.Num(iprofile))],'Color','red','FontSize',14,'BackgroundColor','w');

                    % add to existing data - this could be done in a smarter
                    % way, but for now this is quick and dirty...
%                     app.UIFigure.UserData.num           = [app.UIFigure.UserData.num;num]; % this may be problematic if we already picked some profiles...
%                     app.UIFigure.UserData.start         = [app.UIFigure.UserData.start; T.LonStart(iprofile),T.LatStart(iprofile)];
%                     app.UIFigure.UserData.end           = [app.UIFigure.UserData.end; [T.LonEnd(iprofile),T.LatEnd(iprofile)]];
                    app.UIFigure.UserData.start_handle  = [app.UIFigure.UserData.start_handle; h_start];
                    app.UIFigure.UserData.end_handle    = [app.UIFigure.UserData.end_handle; h_end];
                    app.UIFigure.UserData.line_handle   = [app.UIFigure.UserData.line_handle; h_line];
                    app.UIFigure.UserData.text_handle   = [app.UIFigure.UserData.text_handle; h_text1;h_text2;h_text3];
                    %app.UIFigure.UserData.text_handle   = [app.UIFigure.UserData.text_handle; h_text];
                end
                hold(app.UIAxes,'off')
            end
        end

        % Button pushed function: PicknewprofileButton
        function PicknewprofileButtonPushed(app, event)
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

            % use ginputc to create a new profile
            % [lon1,lat1] = ginput(1); % took forever
            [lon,lat] = ginputc(2,'MarkerSize',5,'ShowPoints',true); % collect as many points as you wish, hit enter to stop
            
            % limit precision
            lon = round(lon,3);
            lat = round(lat,3);

            % plot profile after it is picked
            h_line = plot3(app.UIAxes,lon,lat,[10e3 10e3],'ro-','MarkerFaceColor','r','LineWidth',2);
            app.UIFigure.UserData.num           = [app.UIFigure.UserData.num;length(app.UIFigure.UserData.num)+1];
            app.UIFigure.UserData.start         = [app.UIFigure.UserData.start;[lon(1) lat(1)]];
            app.UIFigure.UserData.end           = [app.UIFigure.UserData.end; [lon(2) lat(2)]];

            % add to existing handles
            app.UIFigure.UserData.line_handle   = [app.UIFigure.UserData.line_handle; h_line];
            app.UIFigure.HandleVisibility = fhv;        % return original state
        end

        % Button pushed function: SaveprofilesButton
        function SaveprofilesButtonPushed(app, event)
            % open save file dialogue
            app.UIFigure.Visible = 'off';
            [FILE,PATH] = uiputfile('*.txt');
            app.UIFigure.Visible = 'on';

            if FILE~=0
                % save the profile data as ascii
                Num         = app.UIFigure.UserData.num;
                LonStart    = app.UIFigure.UserData.start(:,1);
                LatStart    = app.UIFigure.UserData.start(:,2);
                LonEnd      = app.UIFigure.UserData.end(:,1);
                LatEnd      = app.UIFigure.UserData.end(:,2);
                T = table(Num,LonStart,LatStart,LonEnd,LatEnd);
                writetable(T,[PATH,FILE])
            end

        end

        % Button pushed function: ClearprofilesButton
        function ClearprofilesButtonPushed(app, event)
            % delete all handles and stored data
            app.UIFigure.UserData.num           = [];
            app.UIFigure.UserData.start         = [];
            app.UIFigure.UserData.end           = [];
            delete(app.UIFigure.UserData.start_handle);
            delete(app.UIFigure.UserData.end_handle);
            delete(app.UIFigure.UserData.line_handle);
            delete(app.UIFigure.UserData.text_handle);
        end

        % Button pushed function: ModifyProfilesButton
        function ModifyProfilesButtonPushed(app, event)
            % simple solution: check which point is closest to the new pick
            % pick and replace this one

            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

            % use ginputc to create a new profile
            % [lon1,lat1] = ginput(1); % took forever
            [lon_corr,lat_corr] = ginputc(1,'MarkerSize',5,'ShowPoints',true); % collect as many points as you wish, hit enter to stop
            
            % limit precision
            lon_corr = round(lon_corr,3);
            lat_corr = round(lat_corr,3);

            % assemble start and end points of profiles from line handles
            lon_profiles = vertcat(app.UIFigure.UserData.line_handle.XData);
            lat_profiles = vertcat(app.UIFigure.UserData.line_handle.YData);
            [~,ind] = min((lon_profiles(:)-lon_corr).^2+(lat_profiles(:)-lat_corr).^2);
            [iprofile,ipoint] = ind2sub(size(lon_profiles),ind);

            app.UIFigure.UserData.line_handle(iprofile).XData(ipoint) = lon_corr;
            app.UIFigure.UserData.line_handle(iprofile).YData(ipoint) = lat_corr;
            
            if ipoint==1
                app.UIFigure.UserData.start(iprofile,:) = [lon_corr,lat_corr];
            else
                app.UIFigure.UserData.end(iprofile,:) = [lon_corr,lat_corr];
            end

        end

        % Value changed function: LatMax
        function LatMaxValueChanged(app, event)
            value = app.LatMax.Value;
        end

        % Value changed function: LatMin
        function LatMinValueChanged(app, event)
            value = app.LatMin.Value;
        end

        % Value changed function: LonMin
        function LonMinValueChanged(app, event)
            value = app.LonMin.Value;
        end

        % Value changed function: LonMax
        function LonMaxValueChanged(app, event)
            value = app.LonMax.Value;
        end

        % Button pushed function: SetLimitsButton
        function SetLimitsButtonPushed(app, event)
            app.UIAxes.XLim = [ str2double(app.LonMin.Value)  str2double(app.LonMax.Value)];
            app.UIAxes.YLim = [ str2double(app.LatMin.Value)  str2double(app.LatMax.Value)];
        end

        % Button pushed function: DatasetlocationButton
        function DatasetlocationButtonPushed(app, event)
            % load topo file
            [FILE,PATH] = uigetfile("*.jld2");
            
            if FILE~=0
                % write path and file in edit field
                app.DatasetLocationEditField.Value = [PATH,FILE];
            end
        end

        % Button pushed function: AddtoListButton
        function AddtoListButtonPushed(app, event)
            rowdata = {app.DatasetnameEditField.Value,app.DatasetLocationEditField.Value,app.DatasettypeDropDown.Value};
            app.UITable.Data = [app.UITable.Data; rowdata];
        end

        % Button pushed function: SaveListButton
        function SaveListButtonPushed(app, event)
            % save the dataset list to a text file
            [FILE,PATH] = uiputfile("*.txt");
            
            if FILE~=0
                % write path and file in edit field
                writetable(cell2table(app.UITable.Data,'VariableNames',app.UITable.ColumnName),[PATH,FILE]);
            end
        end

        % Callback function
        function ClearSelectedRowButtonPushed(app, event)

        end

        % Button pushed function: ClearListButton
        function ClearListButtonPushed(app, event)
            app.UITable.Data = [];
        end

        % Button pushed function: LoadListButton
        function LoadListButtonPushed(app, event)
            % load the text file with the dataset info
            [FILE,PATH] = uigetfile("*.txt");
            
            if FILE~=0
                tab = readtable([PATH,FILE],"Delimiter",",");
                app.UITable.Data = table2cell(tab); % simply assign
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Longitude')
            ylabel(app.UIAxes, 'Latitude')
            app.LoadTopoButton = uibutton(app.UIFigure, 'push');
            app.LoadTopoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTopoButtonPushed, true);
            app.LoadTopoButton.Text = 'Load Topo';
            app.LoadProfilesButton = uibutton(app.UIFigure, 'push');
            app.LoadProfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProfilesButtonPushed, true);
            app.LoadProfilesButton.Text = 'Load Profiles';
            app.ModifyProfilesButton = uibutton(app.UIFigure, 'push');
            app.ModifyProfilesButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyProfilesButtonPushed, true);
            app.ModifyProfilesButton.Text = 'Modify Profiles';
            app.PicknewprofileButton = uibutton(app.UIFigure, 'push');
            app.PicknewprofileButton.ButtonPushedFcn = createCallbackFcn(app, @PicknewprofileButtonPushed, true);
            app.PicknewprofileButton.Text = 'Pick new profile';
            app.SaveprofilesButton = uibutton(app.UIFigure, 'push');
            app.SaveprofilesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveprofilesButtonPushed, true);
            app.SaveprofilesButton.Text = 'Save profiles';
            app.ClearprofilesButton = uibutton(app.UIFigure, 'push');
            app.ClearprofilesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearprofilesButtonPushed, true);
            app.ClearprofilesButton.Text = 'Clear profiles';
            app.LonMin = uieditfield(app.UIFigure, 'text');
            app.LonMin.ValueChangedFcn = createCallbackFcn(app, @LonMinValueChanged, true);
            app.LonMax = uieditfield(app.UIFigure, 'text');
            app.LonMax.ValueChangedFcn = createCallbackFcn(app, @LonMaxValueChanged, true);
            app.LatMax = uieditfield(app.UIFigure, 'text');
            app.LatMax.ValueChangedFcn = createCallbackFcn(app, @LatMaxValueChanged, true);
            app.LatMin = uieditfield(app.UIFigure, 'text');
            app.LatMin.ValueChangedFcn = createCallbackFcn(app, @LatMinValueChanged, true);
            app.SetLimitsButton = uibutton(app.UIFigure, 'push');
            app.SetLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @SetLimitsButtonPushed, true);
            app.SetLimitsButton.Text = 'Set Limits';
            app.DatasettypeLabel = uilabel(app.UIFigure);
            app.DatasettypeLabel.Text = 'Dataset type';
            app.DatasettypeDropDown = uidropdown(app.UIFigure);
            app.DatasettypeDropDown.Items = {'Volume ', 'Surface ', 'Point '};
            app.DatasettypeDropDown.Value = 'Volume ';
            app.DatasetlocationButton = uibutton(app.UIFigure, 'push');
            app.DatasetlocationButton.ButtonPushedFcn = createCallbackFcn(app, @DatasetlocationButtonPushed, true);
            app.DatasetlocationButton.Text = 'Dataset location';
            app.DatasetnameEditFieldLabel = uilabel(app.UIFigure);
            app.DatasetnameEditFieldLabel.Text = 'Dataset name';
            app.DatasetnameEditField = uieditfield(app.UIFigure, 'text');
            app.AddtoListButton = uibutton(app.UIFigure, 'push');
            app.AddtoListButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoListButtonPushed, true);
            app.AddtoListButton.Text = 'Add to List';
            app.DatasetLocationEditField = uieditfield(app.UIFigure, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'Location'; 'Type'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false false true];
            app.UITable.ColumnEditable = true;
            app.SaveListButton = uibutton(app.UIFigure, 'push');
            app.SaveListButton.ButtonPushedFcn = createCallbackFcn(app, @SaveListButtonPushed, true);
            app.SaveListButton.Text = 'Save List';
            app.ClearListButton = uibutton(app.UIFigure, 'push');
            app.ClearListButton.ButtonPushedFcn = createCallbackFcn(app, @ClearListButtonPushed, true);
            app.ClearListButton.Text = 'Clear List';
            app.LoadListButton = uibutton(app.UIFigure, 'push');
            app.LoadListButton.ButtonPushedFcn = createCallbackFcn(app, @LoadListButtonPushed, true);
            app.LoadListButton.Text = 'Load List';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProfileSelection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end